classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UIAxes     matlab.ui.control.UIAxes
        Button     matlab.ui.control.Button
        Button_3   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        Panel      matlab.ui.container.Panel
        Label      matlab.ui.control.Label
        Spinner    matlab.ui.control.Spinner
        Label_2    matlab.ui.control.Label
        Spinner_2  matlab.ui.control.Spinner
        CheckBox   matlab.ui.control.CheckBox
        Button_2   matlab.ui.control.Button
        UIAxes2    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global imrgb;
            imrgb = imread('pic460x460.png');%从文件读取图像
            imshow(imrgb,'parent',app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global imrgb;
            global imafter;
            imafter = imrgb;
            %imrgb = imread('pic460x460.png');%从文件读取图像
            [m,n,t] = size(imrgb);%读取图片的大小
            for i = 1:m
                for j = 1:n
                    for k = 1:t
                        imafter(i,j,k) = 0.299*imrgb(i,j,1)+0.587*imrgb(i,j,2)+0.11400*imrgb(i,j,3);%加权实现从真彩到灰度的降维转换
                    end
                end
            end
            imshow(imafter,'parent',app.UIAxes2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global imrgb;
            global xscale;
            global yscale;
            global picresize;
            %picresize = ones(460,460)
            [h,w,t] = size(imrgb);%读取图像大小
            ht = round(h./xscale);
            wt = round(w./yscale);
%             for i = 1:ht
%                 for j = 1:wt
%                     for k = 1:t
%                         picresize(i,j,k) = imrgb(round(i*xscale),round(j*xscale),k); 
%                     end
%                 end
%             end
picresize = imresize(imrgb,[ht,wt],'bicubic')
            imshow(picresize,'parent',app.UIAxes2);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global picresize;
            imwrite(picresize,'picresize.jpeg');%以jpeg格式将缩小图保存
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            global xscale;
            xscale = value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            global yscale;
            yscale = value;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if(value == 1)
                app.Spinner_2.Value = app.Spinner.Value
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '图像处理';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'files.png';
            app.Button.Text = '载入图像';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'gongxinbao.png';
            app.Button_3.Text = '缩放原图';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'downloads.png';
            app.Button_4.Text = '保存图片';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '缩放设置';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '横向缩放倍数';
            app.Spinner = uispinner(app.Panel);
            app.Spinner.Limits = [1 10];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '纵向缩放倍数';
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_2.Limits = [1 10];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 1;
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '锁定比例';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'adobe_mix.png';
            app.Button_2.Text = '黑白图像';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '处理后的图像')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end